classdef transfomerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadRLabel                      matlab.ui.control.Label
        CalculatorResultsPanel          matlab.ui.container.Panel
        EfficiencynPanel                matlab.ui.container.Panel
        EfficiencyValueLabel            matlab.ui.control.Label
        CoreLossPanel                   matlab.ui.container.Panel
        CoreLossValueLabel              matlab.ui.control.Label
        CopperLossPanel                 matlab.ui.container.Panel
        CopperLossValueLabel            matlab.ui.control.Label
        OutputPowerPanel                matlab.ui.container.Panel
        OutputPowerValueLabel           matlab.ui.control.Label
        SecondaryCurrentPanel           matlab.ui.container.Panel
        SecondaryCurrentValueLabel      matlab.ui.control.Label
        SecondaryVoltagePanel           matlab.ui.container.Panel
        SecondaryVoltageValueLabel      matlab.ui.control.Label
        LoadSlider                      matlab.ui.control.Slider
        LoadSliderLabel                 matlab.ui.control.Label
        TransformerParametersPanel      matlab.ui.container.Panel
        WLabel_3                        matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        VALabel                         matlab.ui.control.Label
        UnitlessLabel                   matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        CopperlossatratedlossTextArea   matlab.ui.control.TextArea
        TextArea_5Label                 matlab.ui.control.Label
        CorelossconstantTextArea        matlab.ui.control.TextArea
        CorelossconstantTextAreaLabel   matlab.ui.control.Label
        RatedapparentpowerTextArea      matlab.ui.control.TextArea
        TextArea_3Label                 matlab.ui.control.Label
        TurnsratioN_pN_sTextArea        matlab.ui.control.TextArea
        TurnsratioN_pN_sLabel           matlab.ui.control.Label
        PrimaryvoltageRMSTextArea       matlab.ui.control.TextArea
        PrimaryvoltageRMSTextAreaLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TransformerSchemeticPanel       matlab.ui.container.Panel
        ratio_sche                      matlab.ui.control.Label
        secondary_value_sche            matlab.ui.control.Label
        SecondaryLabel                  matlab.ui.control.Label
        primary_value_sche              matlab.ui.control.Label
        PrimaryLabel                    matlab.ui.control.Label
        EfficiencyVsLoad                matlab.ui.control.UIAxes
        LossesVsLoad                    matlab.ui.control.UIAxes
        OpVsLoad                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateTransformer(app, v1_rms, ratio, s_rated, x, core_loss, copper_rated)
            p_out = NaN;
            i2 = NaN;
            copper_loss = NaN;
            n = NaN;

            % Validate turns ratio
            parts = split(string(ratio), ":");
            ratio_valid = false;

            if numel(parts) == 2
                Np = str2double(parts(1));
                Ns = str2double(parts(2));
                ratio_valid = isfinite(Np) && isfinite(Ns) && Np > 0 && Ns > 0;
            end

            % Check and validate other parameters
            voltage_valid = isfinite(v1_rms) && v1_rms > 0;
            power_valid = isfinite(s_rated) && s_rated > 0;
            load_valid = isfinite(x) && x >= 0 && x <= 1;
            core_valid = isfinite(core_loss) && core_loss >= 0;
            copper_valid = isfinite(copper_rated) && copper_rated >= 0;

            % Primary voltage label
            if voltage_valid
                app.primary_value_sche.Text = sprintf("%.2f V (RMS)", v1_rms);
            else
                app.primary_value_sche.Text = "Invalid Voltage";
            end

            % Turns ratio label
            if ratio_valid
                app.ratio_sche.Text = string(ratio);
            else
                app.ratio_sche.Text = "Invalid Ratio";
            end

            % Secondary voltage
            voltage2_valid = voltage_valid && ratio_valid;

            if voltage2_valid
                a = Np / Ns;
                v2_rms = v1_rms / a;

                app.secondary_value_sche.Text = sprintf("%.2f V (RMS)", v2_rms);
                app.SecondaryVoltageValueLabel.Text = sprintf("%.2f V", v2_rms);
            else
                app.secondary_value_sche.Text = "Invalid";
                app.SecondaryVoltageValueLabel.Text = "Invalid";
            end

            % Secondary current and output power
            output_valid = voltage2_valid && power_valid && load_valid;

            if output_valid
                i2_rated = s_rated / v2_rms;
                i2 = i2_rated * x;
                p_out = v2_rms * i2;

                app.SecondaryCurrentValueLabel.Text = sprintf("%.2f A", i2);
                app.OutputPowerValueLabel.Text = sprintf("%.2f W", p_out);
            else
                app.SecondaryCurrentValueLabel.Text = "Invalid";
                app.OutputPowerValueLabel.Text = "Invalid";
            end

            % Core loss
            if core_valid
                app.CoreLossValueLabel.Text = sprintf("%.2f W", core_loss);
            else
                app.CoreLossValueLabel.Text = "Invalid";
            end

            % Copper loss
            copper_loss_valid = copper_valid && load_valid;

            if copper_loss_valid
                copper_loss = copper_rated * x^2;
                app.CopperLossValueLabel.Text = sprintf("%.2f W", copper_loss);
            else
                app.CopperLossValueLabel.Text = "Invalid";
            end

            % Efficiency
            efficiency_valid = output_valid && core_valid && copper_loss_valid;

            if efficiency_valid
                p_loss = core_loss + copper_loss;
                p_in = p_out + p_loss;

                if p_in > 0
                    n = (p_out / p_in) * 100;
                    app.EfficiencyValueLabel.Text = sprintf("%.1f%%", n);
                else
                    app.EfficiencyValueLabel.Text = "N/A";
                end
            else
                app.EfficiencyValueLabel.Text = "Invalid";
            end

            % Variables for plots
            x_vector = linspace(0, 1, 100);
            p_out_vector = s_rated .* x_vector;

            p_copper_vector = x_vector.^2 .* copper_rated;

            p_in_vector = p_out_vector + core_loss + p_copper_vector;
            n_vector = (p_out_vector./p_in_vector) * 100;

            %plotting output power vs. Load
            if all(isfinite(p_out_vector)) && all(p_out_vector >= 0) && isfinite(p_out)
                cla(app.OpVsLoad);
                hold(app.OpVsLoad, 'on');
                plot(app.OpVsLoad, x_vector, p_out_vector, '-');
                plot(app.OpVsLoad, x, p_out, 'o', 'MarkerSize', 10, 'MarkerFaceColor', [0.95 0.25 0.25], 'MarkerEdgeColor', 'white', 'LineWidth', 1.5);
                plot(app.OpVsLoad, [x x], [0 p_out], '--', 'Color', [0.6 0.6 0.6], 'LineWidth', 1.2);
                xlabel(app.OpVsLoad, "Load Fraction (0-1)");
                ylabel(app.OpVsLoad, "Output Power (Watts)");
                title(app.OpVsLoad, "Transformer output power vs. Load");
                legend(app.OpVsLoad, "Power Output (W)", sprintf("Output Power: %.2f W at %.0f%% Load", p_out, x*100), 'Location', 'northwest');
                grid(app.OpVsLoad, 'on');
                hold(app.OpVsLoad, 'off');
            else
                cla(app.OpVsLoad);
            end

            %plotting Transformer Losses vs. Load Fraction
            if copper_loss_valid && core_valid
                cla(app.LossesVsLoad);
                hold(app.LossesVsLoad, 'on');
                plot(app.LossesVsLoad, x_vector, p_copper_vector, 'Color', [0.95 0.45 0.15]);
                yline(app.LossesVsLoad, core_loss, 'Color', [0.20 0.65 0.95]);
                plot(app.LossesVsLoad, x, copper_loss, "o", "MarkerSize", 10, 'MarkerFaceColor', [0.95 0.45 0.15]);
                plot(app.LossesVsLoad, x, core_loss, "o", "MarkerSize", 10, 'MarkerFaceColor', [0.20 0.65 0.95]);
                max_loss = max(copper_loss, core_loss);
                plot(app.LossesVsLoad, [x x], [0 max_loss], '--', 'Color', [0.6 0.6 0.6], 'LineWidth', 1.2);
                title(app.LossesVsLoad, "Transformer losses vs. Load Fraction");
                xlabel(app.LossesVsLoad, "Load Fraction (0-1)");
                ylabel(app.LossesVsLoad, "Power Loss (W)");
                grid(app.LossesVsLoad, 'on');
                legend(app.LossesVsLoad, "Copper Loss", "Core Loss", sprintf("Copper Loss: %.2f W at %.2f%% load", x^2 * copper_rated, x*100), sprintf("Core Loss: %.2f W at %.2f%% Load", core_loss, x*100));
                hold(app.LossesVsLoad, 'off');
            else
                cla(app.LossesVsLoad);
            end

            %plotting efficiency
            if efficiency_valid
                cla(app.EfficiencyVsLoad);
                hold(app.EfficiencyVsLoad, 'on');
                plot(app.EfficiencyVsLoad, x_vector, n_vector, '-', 'LineWidth', 2);
                plot(app.EfficiencyVsLoad, x, n, 'o', 'MarkerSize', 10, 'MarkerFaceColor', [0.20 0.65 0.95]);
                plot(app.EfficiencyVsLoad, [x x], [0 n], '--', 'Color', [0.6 0.6 0.6], 'LineWidth', 1.2);
                xlabel(app.EfficiencyVsLoad, "Load Fraction (0-1)");
                ylabel(app.EfficiencyVsLoad, "Efficiency (%)");
                title(app.EfficiencyVsLoad, "Transformer Efficiency vs. Load Fraction");
                legend(app.EfficiencyVsLoad, "Transformer Efficiency", sprintf("Efficiency: %.2f%% at %.1f%% Load", n, x*100), 'Location', 'southeast');
                ylim(app.EfficiencyVsLoad, [0 100]);
                grid(app.EfficiencyVsLoad, 'on');
                hold(app.EfficiencyVsLoad, 'off');
            else
                cla(app.EfficiencyVsLoad);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIFigure.WindowState = 'maximized';
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1});
            x = app.LoadSlider.Value;
            updateTransformer(app, voltage, ratio, s_rated, x, core_loss, copper_rated);

        end

        % Callback function
        function LoadSliderValueChanged(app, event)
            value = app.LoadSlider.Value;
        end

        % Value changing function: LoadSlider
        function LoadSliderValueChanging(app, event)

            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1})
            updateTransformer(app, voltage, ratio, s_rated, event.Value, core_loss, copper_rated);
        end

        % Value changing function: PrimaryvoltageRMSTextArea
        function PrimaryvoltageRMSTextAreaValueChanging(app, event)
            voltage = str2double(event.Value{1});
            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1});
            updateTransformer(app, voltage, ratio, s_rated, app.LoadSlider.Value, core_loss, copper_rated);
        end

        % Value changing function: TurnsratioN_pN_sTextArea
        function TurnsratioN_pN_sTextAreaValueChanging(app, event)
            ratio = string(event.Value{1});
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1});
            updateTransformer(app, voltage, ratio, s_rated, app.LoadSlider.Value, core_loss, copper_rated);
        end

        % Value changing function: RatedapparentpowerTextArea
        function RatedapparentpowerTextAreaValueChanging(app, event)

            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1});
            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            s_rated = str2double(event.Value{1});
            updateTransformer(app, voltage, ratio, s_rated, app.LoadSlider.Value, core_loss, copper_rated);
        end

        % Value changing function: CorelossconstantTextArea
        function CorelossconstantTextAreaValueChanging(app, event)
            core_loss = str2double(event.Value{1});
            copper_rated = str2double(app.CopperlossatratedlossTextArea.Value{1});
            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            updateTransformer(app, voltage, ratio, s_rated, app.LoadSlider.Value, core_loss, copper_rated);
        end

        % Value changing function: CopperlossatratedlossTextArea
        function CopperlossatratedlossTextAreaValueChanging(app, event)
            copper_rated = str2double(event.Value{1});
            core_loss = str2double(app.CorelossconstantTextArea.Value{1});
            ratio = string(app.TurnsratioN_pN_sTextArea.Value{1});
            voltage = str2double(app.PrimaryvoltageRMSTextArea.Value{1});
            s_rated = str2double(app.RatedapparentpowerTextArea.Value{1});
            updateTransformer(app, voltage, ratio, s_rated, app.LoadSlider.Value, core_loss, copper_rated);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpVsLoad = uiaxes(app.UIFigure);
            title(app.OpVsLoad, 'Title')
            xlabel(app.OpVsLoad, 'X')
            ylabel(app.OpVsLoad, 'Y')
            zlabel(app.OpVsLoad, 'Z')
            app.LossesVsLoad = uiaxes(app.UIFigure);
            title(app.LossesVsLoad, 'Title')
            xlabel(app.LossesVsLoad, 'X')
            ylabel(app.LossesVsLoad, 'Y')
            zlabel(app.LossesVsLoad, 'Z')
            app.EfficiencyVsLoad = uiaxes(app.UIFigure);
            title(app.EfficiencyVsLoad, 'Title')
            xlabel(app.EfficiencyVsLoad, 'X')
            ylabel(app.EfficiencyVsLoad, 'Y')
            zlabel(app.EfficiencyVsLoad, 'Z')
            app.TransformerSchemeticPanel = uipanel(app.UIFigure);
            app.TransformerSchemeticPanel.Title = 'Transformer Schemetic';
            app.PrimaryLabel = uilabel(app.TransformerSchemeticPanel);
            app.PrimaryLabel.Text = 'Primary';
            app.primary_value_sche = uilabel(app.TransformerSchemeticPanel);
            app.primary_value_sche.Text = '120V (RMS)';
            app.SecondaryLabel = uilabel(app.TransformerSchemeticPanel);
            app.SecondaryLabel.Text = 'Secondary';
            app.secondary_value_sche = uilabel(app.TransformerSchemeticPanel);
            app.secondary_value_sche.Text = '12V (RMS)';
            app.ratio_sche = uilabel(app.TransformerSchemeticPanel);
            app.ratio_sche.Text = '10:1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'transformer-removebg-preview.png');
            app.TransformerParametersPanel = uipanel(app.UIFigure);
            app.TransformerParametersPanel.Title = 'Transformer Parameters';
            app.PrimaryvoltageRMSTextAreaLabel = uilabel(app.TransformerParametersPanel);
            app.PrimaryvoltageRMSTextAreaLabel.Interpreter = 'tex';
            app.PrimaryvoltageRMSTextAreaLabel.Text = 'Primary voltage (RMS)';
            app.PrimaryvoltageRMSTextArea = uitextarea(app.TransformerParametersPanel);
            app.PrimaryvoltageRMSTextArea.ValueChangingFcn = createCallbackFcn(app, @PrimaryvoltageRMSTextAreaValueChanging, true);
            app.PrimaryvoltageRMSTextArea.Placeholder = 'Voltage Here';
            app.PrimaryvoltageRMSTextArea.Value = {'120'};
            app.TurnsratioN_pN_sLabel = uilabel(app.TransformerParametersPanel);
            app.TurnsratioN_pN_sLabel.Interpreter = 'tex';
            app.TurnsratioN_pN_sLabel.Text = 'Turns ratio (N_{p}:N_{s})     ';
            app.TurnsratioN_pN_sTextArea = uitextarea(app.TransformerParametersPanel);
            app.TurnsratioN_pN_sTextArea.ValueChangingFcn = createCallbackFcn(app, @TurnsratioN_pN_sTextAreaValueChanging, true);
            app.TurnsratioN_pN_sTextArea.Placeholder = 'Ex: 10:1';
            app.TurnsratioN_pN_sTextArea.Value = {'10:1'};
            app.TextArea_3Label = uilabel(app.TransformerParametersPanel);
            app.TextArea_3Label.Interpreter = 'tex';
            app.TextArea_3Label.Text = 'Rated apparent power';
            app.RatedapparentpowerTextArea = uitextarea(app.TransformerParametersPanel);
            app.RatedapparentpowerTextArea.ValueChangingFcn = createCallbackFcn(app, @RatedapparentpowerTextAreaValueChanging, true);
            app.RatedapparentpowerTextArea.Placeholder = 'Power Value';
            app.RatedapparentpowerTextArea.Value = {'70'};
            app.CorelossconstantTextAreaLabel = uilabel(app.TransformerParametersPanel);
            app.CorelossconstantTextAreaLabel.Interpreter = 'tex';
            app.CorelossconstantTextAreaLabel.Text = 'Core loss (constant)';
            app.CorelossconstantTextArea = uitextarea(app.TransformerParametersPanel);
            app.CorelossconstantTextArea.ValueChangingFcn = createCallbackFcn(app, @CorelossconstantTextAreaValueChanging, true);
            app.CorelossconstantTextArea.Value = {'2'};
            app.TextArea_5Label = uilabel(app.TransformerParametersPanel);
            app.TextArea_5Label.Interpreter = 'tex';
            app.TextArea_5Label.Text = 'Coper loss at rated loss';
            app.CopperlossatratedlossTextArea = uitextarea(app.TransformerParametersPanel);
            app.CopperlossatratedlossTextArea.ValueChangingFcn = createCallbackFcn(app, @CopperlossatratedlossTextAreaValueChanging, true);
            app.CopperlossatratedlossTextArea.Value = {'5'};
            app.VLabel = uilabel(app.TransformerParametersPanel);
            app.VLabel.Text = 'V';
            app.UnitlessLabel = uilabel(app.TransformerParametersPanel);
            app.UnitlessLabel.Text = 'Unitless';
            app.VALabel = uilabel(app.TransformerParametersPanel);
            app.VALabel.Text = 'VA';
            app.WLabel_2 = uilabel(app.TransformerParametersPanel);
            app.WLabel_2.Text = 'W';
            app.WLabel_3 = uilabel(app.TransformerParametersPanel);
            app.WLabel_3.Text = 'W';
            app.LoadSliderLabel = uilabel(app.UIFigure);
            app.LoadSliderLabel.Text = 'Load Fraction (x)';
            app.LoadSlider = uislider(app.UIFigure);
            app.LoadSlider.Limits = [0 1];
            app.LoadSlider.ValueChangingFcn = createCallbackFcn(app, @LoadSliderValueChanging, true);
            app.LoadSlider.StepMode = 'manual';
            app.LoadSlider.Value = 0.7;
            app.CalculatorResultsPanel = uipanel(app.UIFigure);
            app.CalculatorResultsPanel.Title = 'Calculator Results (at current load)';
            app.SecondaryVoltagePanel = uipanel(app.CalculatorResultsPanel);
            app.SecondaryVoltagePanel.Title = 'Secondary Voltage';
            app.SecondaryVoltageValueLabel = uilabel(app.SecondaryVoltagePanel);
            app.SecondaryCurrentPanel = uipanel(app.CalculatorResultsPanel);
            app.SecondaryCurrentPanel.Title = 'Secondary Current';
            app.SecondaryCurrentValueLabel = uilabel(app.SecondaryCurrentPanel);
            app.OutputPowerPanel = uipanel(app.CalculatorResultsPanel);
            app.OutputPowerPanel.Title = 'Output Power';
            app.OutputPowerValueLabel = uilabel(app.OutputPowerPanel);
            app.CopperLossPanel = uipanel(app.CalculatorResultsPanel);
            app.CopperLossPanel.Title = 'Copper Loss';
            app.CopperLossValueLabel = uilabel(app.CopperLossPanel);
            app.CoreLossPanel = uipanel(app.CalculatorResultsPanel);
            app.CoreLossPanel.Title = 'Core Loss';
            app.CoreLossValueLabel = uilabel(app.CoreLossPanel);
            app.EfficiencynPanel = uipanel(app.CalculatorResultsPanel);
            app.EfficiencynPanel.Title = 'Efficiency (n)';
            app.EfficiencyValueLabel = uilabel(app.EfficiencynPanel);
            app.LoadRLabel = uilabel(app.UIFigure);
            app.LoadRLabel.Text = {'Load'; '(R)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transfomerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end